classdef Image_Denoising_Using_Polynominal_Surface_Fitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PolynominalDegreeEditField     matlab.ui.control.EditField
        EnterPolynominalDegreeLabel    matlab.ui.control.Label
        PatchSizeEditField             matlab.ui.control.EditField
        PatchSizeEditFieldLabel        matlab.ui.control.Label
        AfterGaussianSmoothingLabel    matlab.ui.control.Label
        AfterDenoisingProcessLabel     matlab.ui.control.Label
        NoisedGrayScaleImageLabel      matlab.ui.control.Label
        ChosenNoisedImageLabel         matlab.ui.control.Label
        ChoosePictureButton            matlab.ui.control.Button
        ThereisnoresultsrightnowLabel  matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        StartDenoisingButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        SelectedFilePath   % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartDenoisingButton
        function StartDenoisingButtonPushed(app, event)

                if isempty(app.SelectedFilePath)
                    uialert(app.UIFigure, 'You did not choose a picture default picture will be using.', 'Warning');
                    noisy_img = imread("noise.png");
                    app.Image.ImageSource=noisy_img;
                else
                    noisy_img = imread(app.SelectedFilePath);
                
                end
                if size(noisy_img, 3) == 3
                    noisy_img = rgb2gray(noisy_img);
                end
                Patchsize = app.PatchSizeEditField.Value;
                if isempty(Patchsize)
                    patchnumber = 15;  
                else
                    patchnumber = str2double(Patchsize);
                    if isnan(patchnumber)
                        patchnumber = 15;
                    end
                end
                polydegree = app.PolynominalDegreeEditField.Value;
                if isempty(polydegree)
                    polynumber = 2;  
                else
                    polynumber = str2double(polydegree);
                    if isnan(polynumber)
                        polynumber = 2;
                    end
                end
                noisy_img = im2double(noisy_img);
                                
                patch_size = patchnumber;       
                polynomial_degree = polynumber; 
              
                denoised_img = denoise_with_polynomial(noisy_img, patch_size, polynomial_degree);
                
                gaussian_smoothed = imgaussfilt(noisy_img, 2);
                psnr_poly = psnr(denoised_img, noisy_img);
                psnr_gauss = psnr(gaussian_smoothed, noisy_img);
                app.ThereisnoresultsrightnowLabel.Text=sprintf("Polynomial Denoising: %.2f dB \n Gaussian Smoothing: %.2f dB \n ", psnr_poly,psnr_gauss);

                noisy_img_rgb = cat(3, noisy_img, noisy_img, noisy_img);
                app.Image3.ImageSource=noisy_img_rgb;
                denoised_img_rgb = cat(3, denoised_img, denoised_img, denoised_img);
                app.Image2.ImageSource=denoised_img_rgb;
                gaussian_smoothed_rgb = cat(3, gaussian_smoothed, gaussian_smoothed, gaussian_smoothed);
                app.Image4.ImageSource=gaussian_smoothed_rgb;


            function denoised_img = denoise_with_polynomial(img, patch_size, degree)
              
                [rows, cols] = size(img);
                denoised_img = zeros(rows, cols);
                counts = zeros(rows, cols); 
                radius = floor(patch_size/2);
            
                [X, Y] = meshgrid(-radius:radius, -radius:radius);
                X = X(:);
                Y = Y(:);
            
                A = [];
                for d = 0:degree
                    for k = 0:d
                        A = [A, X.^(d-k) .* Y.^k];
                    end
                end
                disp(A);

                for i = radius+1:rows-radius
                    for j = radius+1:cols-radius
                        patch = img(i-radius:i+radius, j-radius:j+radius);
                        patch_values = patch(:);
            
                        coeffs = A \ patch_values;
            
                        reconstructed_patch = reshape(A * coeffs, [patch_size, patch_size]);
            
                        denoised_img(i-radius:i+radius, j-radius:j+radius) = ...
                            denoised_img(i-radius:i+radius, j-radius:j+radius) + reconstructed_patch;
                        counts(i-radius:i+radius, j-radius:j+radius) = ...
                            counts(i-radius:i+radius, j-radius:j+radius) + 1;
                    end
                end
            
                denoised_img = denoised_img ./ counts;
            
                denoised_img(counts == 0) = img(counts == 0);
            end
        end

        % Button pushed function: ChoosePictureButton
        function ChoosePictureButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.png', 'Picture Files (*.png)'; ...
                                   }, ...
                                   'Choose a Picture');
            figure(app.UIFigure);
            if isequal(filename, 0)
                uialert(app.UIFigure, 'Picture not Choosen', 'Warning');
              
            else
                app.SelectedFilePath = fullfile(pathname, filename);
                noisy_img = imread(app.SelectedFilePath);
                app.Image.ImageSource=noisy_img;
               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartDenoisingButton = uibutton(app.UIFigure, 'push');
            app.StartDenoisingButton.ButtonPushedFcn = createCallbackFcn(app, @StartDenoisingButtonPushed, true);
            app.StartDenoisingButton.Text = 'Start Denoising';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.ThereisnoresultsrightnowLabel = uilabel(app.UIFigure);
            app.ThereisnoresultsrightnowLabel.Text = 'There is no results right now.';
            app.ChoosePictureButton = uibutton(app.UIFigure, 'push');
            app.ChoosePictureButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePictureButtonPushed, true);
            app.ChoosePictureButton.Text = 'Choose Picture';
            app.ChosenNoisedImageLabel = uilabel(app.UIFigure);
            app.ChosenNoisedImageLabel.Text = 'Chosen Noised Image';
            app.NoisedGrayScaleImageLabel = uilabel(app.UIFigure);
            app.NoisedGrayScaleImageLabel.Text = 'Noised GrayScale Image';
            app.AfterDenoisingProcessLabel = uilabel(app.UIFigure);
            app.AfterDenoisingProcessLabel.Text = 'After Denoising Process';
            app.AfterGaussianSmoothingLabel = uilabel(app.UIFigure);
            app.AfterGaussianSmoothingLabel.Text = 'After Gaussian Smoothing';
            app.PatchSizeEditFieldLabel = uilabel(app.UIFigure);
            app.PatchSizeEditFieldLabel.Text = 'Patch Size';
            app.PatchSizeEditField = uieditfield(app.UIFigure, 'text');
            app.EnterPolynominalDegreeLabel = uilabel(app.UIFigure);
            app.EnterPolynominalDegreeLabel.Text = 'Polynominal Degree';
            app.PolynominalDegreeEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Denoising_Using_Polynominal_Surface_Fitting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end